classdef lab1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Accum             matlab.ui.control.NumericEditField
        Label_6           matlab.ui.control.Label
        NoiseSKO          matlab.ui.control.NumericEditField
        Label_5           matlab.ui.control.Label
        NoiseType         matlab.ui.control.DropDown
        Label_4           matlab.ui.control.Label
        SignalType        matlab.ui.control.ListBox
        Label_3           matlab.ui.control.Label
        Period            matlab.ui.control.NumericEditField
        EditField_3Label  matlab.ui.control.Label
        Amplitude         matlab.ui.control.NumericEditField
        Label_2           matlab.ui.control.Label
        Point             matlab.ui.control.NumericEditField
        Label             matlab.ui.control.Label
        startButton       matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end


    methods (Access = public)
        
        function results = getNoise(app)
            if app.NoiseType.Value == "нормальный"
                results = randn(app.Point.Value, app.Accum.Value);
            elseif app.NoiseType.Value == "белый"
                results = wgn(app.Point.Value, app.Accum.Value, 0);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function cotopesSliderValueChanged(app, event)

            
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            points = app.Point.Value;
            amplitude = app.Amplitude.Value;
            n_periods = app.Period.Value;
            signal_type = app.SignalType.Value
            noiseSKO = app.NoiseSKO.Value;
            noiseArr = getNoise(app);
            accums = app.Accum.Value


            for point=1:points
                 y(point) = 0;
                for accum_index=1:accums
                    if signal_type == "синус"
                        y(point) = y(point) + amplitude*sin(point*2*pi*n_periods/points)+ ...
                            noiseSKO*noiseArr(point,accum_index);
                    elseif signal_type == "пилообразная"
                        y(point) = y(point) + (2*amplitude/pi)*acot(tan(n_periods*pi*point/points)) + ...
                            noiseSKO*noiseArr(point,accum_index);         
                    elseif signal_type == "треугольная"
                         y(point) = y(point) + (2*amplitude/pi)*asin(sin(n_periods*2*pi*point/points)) + ...
                            noiseSKO*noiseArr(point,accum_index);
                    elseif signal_type == "прямоугольная"
                        y(point) = y(point) + amplitude*sign(sin(n_periods*2*pi*point/points)) + ...
                            noiseSKO*noiseArr(point,accum_index);
                    end
                end
               y(point) = y(point) / 6;
            end
            plot(app.UIAxes, y);
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Генерация сигнала'; ''})
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'y(t)')
            zlabel(app.UIAxes, 'Z')
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Tag = 'start_btn';
            app.startButton.Text = 'start';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'точек';
            app.Point = uieditfield(app.UIFigure, 'numeric');
            app.Point.Value = 1024;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'амплитуда';
            app.Amplitude = uieditfield(app.UIFigure, 'numeric');
            app.Amplitude.Value = 1;
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = 'периодов';
            app.Period = uieditfield(app.UIFigure, 'numeric');
            app.Period.Value = 1;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {'тип сигнала'; ''};
            app.SignalType = uilistbox(app.UIFigure);
            app.SignalType.Items = {'синус', 'пилообразная', 'треугольная', 'прямоугольная', '', ''};
            app.SignalType.Value = 'синус';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = {'тип шума'; ''};
            app.NoiseType = uidropdown(app.UIFigure);
            app.NoiseType.Items = {'нормальный', 'белый'};
            app.NoiseType.Value = 'нормальный';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'ско шума';
            app.NoiseSKO = uieditfield(app.UIFigure, 'numeric');
            app.NoiseSKO.Value = 0.5;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'кол-во накоплений';
            app.Accum = uieditfield(app.UIFigure, 'numeric');
            app.Accum.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end